classdef quantifyParticleIntensityGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        uitoolbar1             matlab.ui.container.Toolbar
        uitoggletool9          matlab.ui.container.toolbar.ToggleTool
        uitoggletool10         matlab.ui.container.toolbar.ToggleTool
        uitoggletool11         matlab.ui.container.toolbar.ToggleTool
        SliceSpinner           matlab.ui.control.Spinner
        SliceSpinnerLabel      matlab.ui.control.Label
        TimePointSpinner       matlab.ui.control.Spinner
        TimePointSpinnerLabel  matlab.ui.control.Label
        togglebutton2          matlab.ui.control.StateButton
        togglebutton1          matlab.ui.control.StateButton
        text7                  matlab.ui.control.Label
        uitable1               matlab.ui.control.Table
        pushbutton3            matlab.ui.control.Button
        text6                  matlab.ui.control.Label
        pushbutton2            matlab.ui.control.Button
        pushbutton1            matlab.ui.control.Button
        axes2                  matlab.ui.control.UIAxes
        axes1                  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function image = checkToggleChannel(app, contrastedImageVirus, contrastedImageNucleus, handles)
            virusBool = get(handles.togglebutton1,'Value');
            nucleusBool = get(handles.togglebutton2,'Value');
            if virusBool == 1 && nucleusBool == 1 %show both
            image = imfuse(contrastedImageNucleus, contrastedImageVirus,'ColorChannels',[1 2 0]); %nucleus is red; virus is green
            end
            if virusBool == 1 && nucleusBool == 0 %show virus only
                contrastedImageNucleus(:)=0;
                image = cat(3, contrastedImageNucleus, contrastedImageVirus, contrastedImageNucleus);
            end
            if virusBool == 0 && nucleusBool == 1 %show nucleus only
                contrastedImageVirus(:)=0;
                image = cat(3, contrastedImageNucleus, contrastedImageVirus, contrastedImageVirus);
            end
        end
        
        function [particleMaxIntensity, particleSumIntensity, LocalBkg, LocalBkgSUM] = extractIntensity(app, currentImage, c0x, c0y)
            imageWidth = size(currentImage,2);
            imageHeight = size(currentImage,1);
            scalingFactor = 5;
            Xmax = c0x + scalingFactor;
            if Xmax > imageWidth
                Xmax = imageWidth;
            end
            Xmin = c0x - scalingFactor;
            if Xmin < 1
                Xmin = 1;
            end
            Ymax = c0y + scalingFactor;
            if Ymax > imageHeight
                Ymax = imageHeight;
            end
            Ymin = c0y - scalingFactor;
            if Ymin < 1
                Ymin = 1;
            end
            Xmax = round(Xmax);
            Xmin = round(Xmin);
            Ymax = round(Ymax);
            Ymin = round(Ymin);
            valuesArray = currentImage(Ymin:Ymax,Xmin:Xmax);
            particleMaxIntensity = max(valuesArray(:));
            particleSumIntensity = sum(valuesArray(:));
            XmaxLocal = Xmax + 2;
            if XmaxLocal > imageWidth
                XmaxLocal = imageWidth;
            end
            XminLocal = Xmin - 2;
            if XminLocal < 1
                XminLocal = 1;
            end
            YmaxLocal = Ymax + 2;
            if YmaxLocal > imageHeight
                YmaxLocal = imageHeight;
            end
            YminLocal = Ymin - 2;
            if YminLocal < 1
                YminLocal = 1;
            end
            %extract intensities from image
            tempImage = double(currentImage);
            tempImage(Ymin:Ymax,Xmin:Xmax) = NaN; %removes particle values when calculating local background
            localIntensities = tempImage(YminLocal:YmaxLocal,XminLocal:XmaxLocal);
            LocalBkGAVG = mean(localIntensities(:),"omitnan");
            LocalBkg = round(LocalBkGAVG);
            valuesArray2 = double(valuesArray) - LocalBkGAVG;
            LocalBkgSUM = sum(valuesArray2(:));
        end
        
        function [imageMaxNuc, imageMaxVirus] = maximumIntensityProjection(app, handles)
            numSlices = handles.numSlices;
            currentTimePoint = handles.currentTimePoint;
            currentTimePointBottomSlice = (currentTimePoint * numSlices)-(numSlices)+1;
            nucleusImage = handles.nucleusImage;
            currentNucleusImage = imread(nucleusImage,'Index',currentTimePointBottomSlice);
            imageHeight = size(currentNucleusImage,1);
            imageWidth = size(currentNucleusImage,2);
            virusImage = handles.virusImage;
            
            %import stacks
            xytStackNuc = zeros([imageHeight,imageWidth,numSlices], 'uint16');
            xytStackVirus = zeros([imageHeight,imageWidth,numSlices], 'uint16');
            i = currentTimePointBottomSlice;
            lastImage = currentTimePointBottomSlice+numSlices-1;
            k = 1;
            while i <= lastImage
                currentImage = imread(nucleusImage,'Index',i);
                xytStackNuc(:,:,k) = currentImage;
                currentImage = imread(virusImage,'Index',i);
                xytStackVirus(:,:,k) = currentImage;
                i = i + 1;
                k = k + 1;
            end
            imageMaxNuc = max(xytStackNuc,[],3);
            imageMaxVirus = max(xytStackVirus,[],3);
            imageMaxNuc = uint16(imageMaxNuc);
            imageMaxVirus = uint16(imageMaxVirus);
        end
        
        function showImages(app, handles)
            contrastedImageNucleus = imadjust(handles.currentNucleusImage,[handles.minNucleus handles.maxNucleus],[0 1]);
            contrastedImageVirus = imadjust(handles.currentVirusImage,[handles.minVirus handles.maxVirus],[0 1]);
            %check toggle if only showing virus channel or nucleus channel
            [image] = checkToggleChannel(app, contrastedImageVirus,contrastedImageNucleus,handles);
            axes(handles.axes1)
            L = handles.axesZoom; %will get modified if using zoomin/zoomout/pan tools
            imshow(image);
            set(gca,{'xlim','ylim'},L)
            %do the same for MIPs
            contrastedImageNucleus = imadjust(handles.MaxNucImage,[handles.minNucleus handles.maxNucleus],[0 1]);
            contrastedImageVirus = imadjust(handles.MaxVirusImage,[handles.minVirus handles.maxVirus],[0 1]);
            [image] = checkToggleChannel(app, contrastedImageVirus,contrastedImageNucleus,handles);
            axes(handles.axes2)
            imshow(image);
            set(gca,{'xlim','ylim'},L)
            handles.image = image;
            impixelinfo(handles.axes1);
        end
        
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool9, app.uitoggletool10, app.uitoggletool11];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.figure1, 'off')
            rotate3d(app.figure1, 'off');
            pan(app.figure1, 'off');
            zoom(app.figure1,'off');
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
             app.uitable1.ColumnFormat = {[] [] [] [] [] [] [] [] []};
%            app.uitable2.BackgroundColor = [1 1 1;0.9412 0.9412 0.9412];
%            app.uitable2.ColumnFormat = {[] [] [] [] [] [] [] []};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function quantifyIntensityGUI_OpeningFcn(app, varargin)
            % --- Executes just before quantifyIntensityGUI is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to quantifyIntensityGUI (see VARARGIN)
            
            %input intial data into handles
            handles.nucleusChannel = varargin{1,1}; %channel 1 or 2
            handles.numSlices = varargin{1,2}; %num slices in z-stack
            handles.inputImageCH1 = varargin{1,3}; %includes full file path for tif stack
            handles.inputImageCH2 = varargin{1,4}; %includes full file path for tif stack
            handles.numTimePoints = varargin{1,5}; %number of time points in tif stack
            handles.inputImageCH3 = varargin{1,6}; %includes full file path for tif stack - secondary particle channel
            handles.currentSlice = 1; %automatically load first slice from image
            handles.currentTimePoint = 1;
            handles.currentTableRow = 1;
            handles.tableData = zeros(0,6);
            handles.currentTableRowNucleus = 1;
            handles.minNucleus = 0;
            handles.maxNucleus = .03;
            handles.minVirus = 0;
            handles.maxVirus = .03;
            %automatically load image from first z-stack
            nucleusChannel = handles.nucleusChannel;
            if nucleusChannel == 1
            imageCH1 = handles.inputImageCH1;
            nucleusImage = imageCH1;
            imageCH2 = handles.inputImageCH2;
            virusImage = imageCH2;
            handles.nucleusImage = nucleusImage;
            handles.virusImage = virusImage;
            else
            imageCH1 = handles.inputImageCH1;
            virusImage = imageCH1;
            imageCH2 = handles.inputImageCH2;
            nucleusImage = imageCH2;
            handles.nucleusImage = nucleusImage;
            handles.virusImage = virusImage;
            end
            
            %Place image inside current axes
            axes(handles.axes1)
            currentNucleusImage = imread(nucleusImage,'Index',1);
            handles.currentNucleusImage = currentNucleusImage;
            newNucleus = imadjust(currentNucleusImage,[handles.minNucleus handles.maxNucleus],[0 1]);
            currentVirusImage = imread(virusImage,'Index',1);
            handles.currentVirusImage = currentVirusImage;
            newVirus = imadjust(currentVirusImage,[handles.minVirus handles.maxVirus],[0 1]);
            compImage = imfuse(newNucleus, newVirus,'ColorChannels',[1 2 0]); %nucleus is red; virus is green
            image(compImage);
            handles.image = compImage;
            axis off
            axis image
            handles.axesZoom = get(gca,{'xlim','ylim'});  % Get axes limits.
            impixelinfo(handles.axes1);
            
            %Load MIP
            [imageMaxNuc,imageMaxVirus] = maximumIntensityProjection(app, handles);
            contrastedImageNucleus = imadjust(imageMaxNuc,[handles.minNucleus handles.maxNucleus],[0 1]);
            contrastedImageVirus = imadjust(imageMaxVirus,[handles.minVirus handles.maxVirus],[0 1]);
            compImg = imfuse(contrastedImageNucleus, contrastedImageVirus,'ColorChannels',[1 2 0]); %nucleus is red; virus is green
            axes(handles.axes2)
            zoom reset
            L = handles.axesZoom; %will get modified if using zoomin/zoomout/pan tools
            imshow(compImg);
            axis off
            set(gca,{'xlim','ylim'},L)
            handles.MaxNucImage = imageMaxNuc;
            handles.MaxVirusImage = imageMaxVirus;
            handles.MaxComposite = compImg;
            
            % Choose default command line output for quantifyIntensityGUI
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % --- Adjust contrast for nucleus channel
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %adjusts contrast for nucleusChannel
            nucleusImage = handles.currentNucleusImage;
            figureHandle = figure;
            imshow(nucleusImage);
            hTool = imcontrast(figureHandle);
            waitfor(msgbox('Click OK when finished adjusting contrast.'));
            outMin = str2double(get(findobj(hTool, 'tag', 'window min edit'), 'String'));
            outMax = str2double(get(findobj(hTool, 'tag', 'window max edit'), 'String'));
            delete(figureHandle);
            %convert min and max to fraction
            outMin2 = outMin/65535;
            outMax2 = outMax/65535;
            handles.minNucleus = outMin2;
            handles.maxNucleus = outMax2;
            showImages(app, handles)
            set(handles.pushbutton1,'Value',0);
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % --- Adjust contrast for virus channel
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %adjusts contrast for virusChannel
            virusImage = handles.currentVirusImage;
            figureHandle = figure;
            imshow(virusImage);
            hTool = imcontrast(figureHandle);
            waitfor(msgbox('Click OK when finished adjusting contrast.'));
            outMin = str2double(get(findobj(hTool, 'tag', 'window min edit'), 'String'));
            outMax = str2double(get(findobj(hTool, 'tag', 'window max edit'), 'String'));
            delete(figureHandle);
            %convert min and max to fraction
            outMin2 = outMin/65535;
            outMax2 = outMax/65535;
            handles.minVirus = outMin2;
            handles.maxVirus = outMax2;
            showImages(app, handles)
            set(handles.pushbutton2,'Value',0);
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % --- Select xy coordinates in virus channel for intensity calculations
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            axes(handles.axes1)
            [xi2,yi2,~] = impixel;  %hold shift and click to select single point
            c0x = xi2;
            c0y = yi2;
            currentImage = handles.currentVirusImage;
            [~, ~, ~, SumMinusLocalBkg] = extractIntensity(app, currentImage,c0x,c0y); %sum intensity (not max intensity)
            %Check to make sure this is slice of particle, and not above or below
            %first, check above...
            currentSlice = handles.currentSlice;
            numSlices = handles.numSlices;
            currentTimePoint = handles.currentTimePoint;
            %check above
            currentSlice2 = currentSlice + 1;
            if currentSlice2 > numSlices 
                SumMinusLocalBkgAbove = 0; %above z-stack
            else
                currentTimePointBottomSlice = (currentTimePoint * numSlices)-(numSlices)+1;
                currentImageNumber = currentTimePointBottomSlice + currentSlice - 1;
                tempVirusImageAbove = imread(handles.virusImage,'Index',currentImageNumber+1); %slice above
                [~, ~, ~, SumMinusLocalBkgAbove] = extractIntensity(app, tempVirusImageAbove,c0x,c0y);
            end
            %Next, check below...
            currentSlice2 = currentSlice - 1;
            if currentSlice2 < 1
                SumMinusLocalBkgBelow = 0;
            else
                currentTimePointBottomSlice = (currentTimePoint * numSlices)-(numSlices)+1;
                currentImageNumber = currentTimePointBottomSlice + currentSlice - 1;
                tempVirusImageBelow = imread(handles.virusImage,'Index',currentImageNumber-1); %slice below
                [~, ~, ~, SumMinusLocalBkgBelow] = extractIntensity(app, tempVirusImageBelow,c0x,c0y);
            end
            %Get intensity from slice with highest intensity
            bool = 0;
            if SumMinusLocalBkg >= SumMinusLocalBkgAbove && SumMinusLocalBkg >= SumMinusLocalBkgBelow
                [~,~,~,LocalBkgSUM2] = extractIntensity(app, currentImage,c0x,c0y);
                bool = 0;
            end
            if SumMinusLocalBkgAbove > SumMinusLocalBkg && SumMinusLocalBkgAbove > SumMinusLocalBkgBelow
                [~,~,~,LocalBkgSUM2] = extractIntensity(app, tempVirusImageAbove,c0x,c0y);
                bool = 1;
            end
            if SumMinusLocalBkgBelow > SumMinusLocalBkgAbove && SumMinusLocalBkgBelow > SumMinusLocalBkg
                [~,~,~,LocalBkgSUM2] = extractIntensity(app, tempVirusImageBelow,c0x,c0y);
                bool = -1;
            end

            currentTable = handles.tableData;
            currentTableRow = handles.currentTableRow;
            currentTable(currentTableRow,1) = handles.currentTimePoint;
            currentTable(currentTableRow,2) = currentSlice + bool;
            currentTable(currentTableRow,3) = c0x;
            currentTable(currentTableRow,4) = c0y;
            currentTable(currentTableRow,5) = round(LocalBkgSUM2); %background subtracted-intensities

            %Extract intensity second particle channel
            %exists - Use same slice as primary virus channel
            if handles.inputImageCH3 ~= 1
            currentImageNumber3 = currentImageNumber + bool;
            currentImage = imread(handles.inputImageCH3,'Index',currentImageNumber3); 
            [~,~,~,LocalBkgSUM2] = extractIntensity(app, currentImage,c0x,c0y);
            currentTable(currentTableRow,6) = round(LocalBkgSUM2);
            else
            currentTable(currentTableRow,6) = 0;
            end

            handles.tableData = currentTable;
            set(handles.uitable1,'data',currentTable);
            newTableRow = currentTableRow + 1;
            handles.currentTableRow = newTableRow;
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: togglebutton1
        function togglebutton1_Callback(app, event)
            % --- Toggle virus channel
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to togglebutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %change images immediately when toggle button is pressed
            showImages(app, handles)
        end

        % Value changed function: togglebutton2
        function togglebutton2_Callback(app, event)
            % --- Toggle nucleus channel
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to togglebutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %change images immediately when toggle button is pressed
            showImages(app, handles)
        end

        % Off callback: uitoggletool10
        function uitoggletool10_OffCallback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to uitoggletool10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            axes(handles.axes1)
            handles.axesZoom = get(gca,{'xlim','ylim'});  % Get axes limits.
            showImages(app, handles)
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Off callback: uitoggletool11
        function uitoggletool11_OffCallback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to uitoggletool11 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            axes(handles.axes1)
            handles.axesZoom = get(gca,{'xlim','ylim'});  % Get axes limits.
            showImages(app, handles)
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Off callback: uitoggletool9
        function uitoggletool9_OffCallback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to uitoggletool9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            axes(handles.axes1)
            handles.axesZoom = get(gca,{'xlim','ylim'});  % Get axes limits.
            showImages(app, handles)
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Callback function: uitoggletool11
        function uitoggletool11_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool11.State;
            pan(app.figure1, char(state));
        end

        % Callback function: uitoggletool10
        function uitoggletool10_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.uitoggletool10.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Callback function: uitoggletool9
        function uitoggletool9_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.uitoggletool9.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Value changed function: TimePointSpinner
        function TimePointSpinnerValueChanged(app, event)
            
            % --- Change Time Point
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %value = app.TimePointSpinner.Value;
            %Time point
            currentTimePoint = app.TimePointSpinner.Value;
            if currentTimePoint <= 0
                currentTimePoint = 1;
            end
            if currentTimePoint > handles.numTimePoints
                currentTimePoint = handles.numTimePoints;
            end
            handles.currentTimePoint = currentTimePoint;
            app.TimePointSpinner.Value = currentTimePoint;
            %get new image based on Time point (stay on current slice - defined in handles)
            numSlices = handles.numSlices;
            currentTimePointBottomSlice = (currentTimePoint * numSlices)-(numSlices)+1;
            currentSlice = handles.currentSlice;
            currentImageNumber = round(currentTimePointBottomSlice + currentSlice - 1);
            nucleusImage = handles.nucleusImage;
            virusImage = handles.virusImage;
            currentNucleusImage = imread(nucleusImage,'Index',currentImageNumber);
            currentVirusImage = imread(virusImage,'Index',currentImageNumber);
            handles.currentNucleusImage = currentNucleusImage;
            handles.currentVirusImage = currentVirusImage;
            %also change maximum projection image when changing time point
            [imageMaxNuc,imageMaxVirus] = maximumIntensityProjection(app, handles);
            handles.MaxNucImage = imageMaxNuc;
            handles.MaxVirusImage = imageMaxVirus;
            showImages(app, handles)
         
            % Update handles structure
            guidata(hObject, handles);          

        end

        % Value changed function: SliceSpinner
        function SliceSpinnerValueChanged(app, event)
            % --- Change Slice
          
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            currentSlice = app.SliceSpinner.Value;
            if currentSlice <= 0
                currentSlice = 1;
            end
            if currentSlice > handles.numSlices
                currentSlice = handles.numSlices;
            end
            handles.currentSlice = currentSlice;
            %get new image based on new slice (stay on same time point - defined in handles)
            currentTimePoint = handles.currentTimePoint;
            numSlices = handles.numSlices;
            currentTimePointBottomSlice = (currentTimePoint * numSlices)-(numSlices)+1;
            currentImageNumber = currentTimePointBottomSlice + currentSlice - 1;
            nucleusImage = handles.nucleusImage;
            virusImage = handles.virusImage;
            currentNucleusImage = imread(nucleusImage,'Index',currentImageNumber);
            currentVirusImage = imread(virusImage,'Index',currentImageNumber);
            handles.currentNucleusImage = currentNucleusImage;
            handles.currentVirusImage = currentVirusImage;
            showImages(app, handles) %MIP is unchanged...just grab from handles
            
            % Update handles structure
            guidata(hObject, handles); 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'quantifyIntensityGUI';
            app.figure1.Tag = 'figure1';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uitoggletool9 = uitoggletool(app.uitoolbar1);
            app.uitoggletool9.Tag = 'uitoggletool9';
            app.uitoggletool9.Tooltip = 'Zoom In';
            app.uitoggletool9.ClickedCallback = createCallbackFcn(app, @uitoggletool9_ClickedCallback, true);
            app.uitoggletool9.Icon = 'uitoggletool9_image.png';
            app.uitoggletool9.OffCallback = createCallbackFcn(app, @uitoggletool9_OffCallback, true);
            app.uitoggletool10 = uitoggletool(app.uitoolbar1);
            app.uitoggletool10.Tag = 'uitoggletool10';
            app.uitoggletool10.Tooltip = 'Zoom Out';
            app.uitoggletool10.ClickedCallback = createCallbackFcn(app, @uitoggletool10_ClickedCallback, true);
            app.uitoggletool10.Icon = 'uitoggletool10_image.png';
            app.uitoggletool10.OffCallback = createCallbackFcn(app, @uitoggletool10_OffCallback, true);
            app.uitoggletool11 = uitoggletool(app.uitoolbar1);
            app.uitoggletool11.Tag = 'uitoggletool11';
            app.uitoggletool11.Tooltip = 'Pan';
            app.uitoggletool11.ClickedCallback = createCallbackFcn(app, @uitoggletool11_ClickedCallback, true);
            app.uitoggletool11.Icon = 'uitoggletool11_image.png';
            app.uitoggletool11.OffCallback = createCallbackFcn(app, @uitoggletool11_OffCallback, true);
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.Tag = 'axes2';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Adjust nucleus contrast';
            app.pushbutton2 = uibutton(app.figure1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = 'Adjust virus contrast';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Single slice';
            app.pushbutton3 = uibutton(app.figure1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = 'Identify particle';
            app.uitable1 = uitable(app.figure1);
            app.uitable1.ColumnName = {'Time Point'; 'Slice'; 'X Coord'; 'Y Coord'; 'Intensity-Ch1'; 'Intensity-Ch2'};
            app.uitable1.ColumnEditable = [false false false false false false];
            app.uitable1.Tag = 'uitable1';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Maximum Intensity Projection';
            app.togglebutton1 = uibutton(app.figure1, 'state');
            app.togglebutton1.ValueChangedFcn = createCallbackFcn(app, @togglebutton1_Callback, true);
            app.togglebutton1.Tag = 'togglebutton1';
            app.togglebutton1.Text = 'Virus channel';
            app.togglebutton1.Value = true;
            app.togglebutton2 = uibutton(app.figure1, 'state');
            app.togglebutton2.ValueChangedFcn = createCallbackFcn(app, @togglebutton2_Callback, true);
            app.togglebutton2.Tag = 'togglebutton2';
            app.togglebutton2.Text = 'Nucleus channel';
            app.togglebutton2.Value = true;
            app.TimePointSpinnerLabel = uilabel(app.figure1);
            app.TimePointSpinnerLabel.Text = 'Time Point';
            app.TimePointSpinner = uispinner(app.figure1);
            app.TimePointSpinner.ValueChangedFcn = createCallbackFcn(app, @TimePointSpinnerValueChanged, true);
            app.TimePointSpinner.Value = 1;
            app.SliceSpinnerLabel = uilabel(app.figure1);
            app.SliceSpinnerLabel.Text = 'Slice';
            app.SliceSpinner = uispinner(app.figure1);
            app.SliceSpinner.ValueChangedFcn = createCallbackFcn(app, @SliceSpinnerValueChanged, true);
            app.SliceSpinner.Value = 1;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = quantifyParticleIntensityGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)quantifyIntensityGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end